classdef BreathingSensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BreathingSensorUIFigure      matlab.ui.Figure
        secLabel                     matlab.ui.control.Label
        DisplayRatesEditField        matlab.ui.control.NumericEditField
        DisplayRatesEditFieldLabel   matlab.ui.control.Label
        InvertDropDown               matlab.ui.control.DropDown
        InvertDropDownLabel          matlab.ui.control.Label
        ShowRatesCheckBox            matlab.ui.control.CheckBox
        ResetRatesButton             matlab.ui.control.Button
        ShowTrig                     matlab.ui.control.CheckBox
        AlgorithmCheckBox            matlab.ui.control.CheckBox
        ShowPeaksCheckBox            matlab.ui.control.CheckBox
        minLabel_2                   matlab.ui.control.Label
        minLabel                     matlab.ui.control.Label
        HeartRateEditField           matlab.ui.control.NumericEditField
        HeartRateLabel               matlab.ui.control.Label
        StartButton                  matlab.ui.control.StateButton
        BreathingRateEditField       matlab.ui.control.NumericEditField
        BreathingRateEditFieldLabel  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        HRDetectDropDown             matlab.ui.control.DropDown
        HRDetectDropDownLabel        matlab.ui.control.Label
        Trigger                      matlab.ui.control.DropDown
        TriggerLabel                 matlab.ui.control.Label
        RRDetectDropDown             matlab.ui.control.DropDown
        RRDetectDropDownLabel        matlab.ui.control.Label
        FilterDropDown               matlab.ui.control.DropDown
        FilterDropDownLabel          matlab.ui.control.Label
        MaxBreathingRateEditField    matlab.ui.control.NumericEditField
        MaxRateLabel                 matlab.ui.control.Label
        BreathProminenceEditField    matlab.ui.control.NumericEditField
        ProminenceEditFieldLabel     matlab.ui.control.Label
        PathTextArea                 matlab.ui.control.TextArea
        PathTextAreaLabel            matlab.ui.control.Label
        FileEditField                matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        LowerEditField               matlab.ui.control.NumericEditField
        LowerEditFieldLabel          matlab.ui.control.Label
        UpperEditField               matlab.ui.control.NumericEditField
        UpperEditFieldLabel          matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
        TimesEditFieldLabel          matlab.ui.control.Label
        SampleRateEditField          matlab.ui.control.NumericEditField
        RateHzLabel                  matlab.ui.control.Label
        ToggleNumberEditField        matlab.ui.control.NumericEditField
        bpmLabel                     matlab.ui.control.Label
        rpmLabel                     matlab.ui.control.Label
        ProminenceButton             matlab.ui.control.Button
        HeartProminenceEditField     matlab.ui.control.NumericEditField
        MaxHeartRateEditField        matlab.ui.control.NumericEditField
        LogRatestoFileCheckBox       matlab.ui.control.CheckBox
        SavetoFileCheckBox           matlab.ui.control.CheckBox
        FileSelectButton             matlab.ui.control.Button
        AutoRangeCheckBox            matlab.ui.control.CheckBox
        HRRateAxes                   matlab.ui.control.UIAxes
        RRRateAxes                   matlab.ui.control.UIAxes
        HeartDisplay                 matlab.ui.control.UIAxes
        BreathingDisplay             matlab.ui.control.UIAxes
        VitalsDisplay                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Rate % sampling rate for Arduino, limited to 5kHz
        Data % data collected from sensor by Arduino
        TrigData % trigger data collected by Arduino
        Time % time data for collection
        Trace % displayed voltage trace from sensor
        TrigTrace % data collected by trigger pin on Arduino
        FID % binary file identifier
        Arduino % serial port for Arduino communications
        RespPeaks % displayed peak points for respiratory peaks
        HeartPeaks % displayed peak points for heartbeats
        logid % file ID for tracking heart and breathing rates
        RespTrace % displayed processed respiratory signal
        HeartTrace % displayed processed cardiac signal
        show_algorithm % flag indicating if algorithm is being shown
        HRtrace % estimated heart rate over last 60 seconds
        RRtrace % estimated respiratory rate over last 60 seconds
        HRdata % vector of estimated respiratory rates over 180 seconds
        RRdata % vector of estimated respiratory rates over 180 seconds
        AutoProm % boolean to indicate if automatic estimation has been performed
    end
    
    methods (Access = private)
        
        function recordData(app,src,~)
            counts = app.Arduino.BytesAvailableFcnCount/2;
            rawpacket = fread(src,counts,'uint16');
            if app.SavetoFileCheckBox.Value
                fwrite(app.FID,uint16(rawpacket),'uint16');
            end
            
            trigpacket = rawpacket>=(2^15);
            packet = (rawpacket-2^15*trigpacket)*5/1023;
            app.Data(1:counts) = packet;
            app.TrigData(1:counts) = trigpacket;
            timePacket = app.Time(end)+(1:counts)/app.Rate;
            app.Time(1:counts) = timePacket;
            
            app.Data = circshift(app.Data,-counts,1);
            app.TrigData = circshift(app.TrigData,-counts,1);
            app.Time = circshift(app.Time,-counts,1);
            
            opt = find(contains(app.FilterDropDown.Items,app.FilterDropDown.Value));
            TimeLast = app.TimeEditField.Value;
            
            switch opt
                case 1 % no filter
                    points = app.TimeEditField.Value*app.Rate;
                    resp = app.Data(end-points+1:end);
                    trig = app.TrigData(end-points+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = 0;
                case 2 % 20 Hz moving average filter
                    FL = ceil(app.Rate/20);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    trig = app.TrigData(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    trig = trig(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 3 % 40 Hz moving average filter
                    FL = ceil(app.Rate/40);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    trig = app.TrigData(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    trig = trig(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 4 % 50 Hz moving average filter
                    FL = ceil(app.Rate/50);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    trig = app.TrigData(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    trig = trig(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 5 % 60 Hz moving average filter
                    FL = ceil(app.Rate/60);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    trig = app.TrigData(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    trig = trig(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 6 % 50 Hz lowpass filter
                    FL = ceil(app.Rate/50);
                    [b,a] = butter(1,50/(app.Rate/2),'low');
                    points = app.TimeEditField.Value*app.Rate;
                    resp = app.Data(end-points-FL+1:end);
                    trig = app.TrigData(end-points-FL+1:end);
                    if isnan(resp(1))
                        idx = find(~isnan(resp),1,'first');
                        resp(1:idx) = resp(idx);
                    end
                    resp = filter(b,a,resp);
                    resp = resp(FL+1:end);
                    trig = trig(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = 0;
                case 7 % 100 Hz lowpass filter
                    FL = ceil(app.Rate/100);
                    [b,a] = butter(1,100/(app.Rate/2),'low');
                    points = app.TimeEditField.Value*app.Rate;
                    resp = app.Data(end-points-FL+1:end);
                    trig = app.TrigData(end-points-FL+1:end);
                    if isnan(resp(1))
                        idx = find(~isnan(resp),1,'first');
                        resp(1:idx) = resp(idx);
                    end
                    resp = filter(b,a,resp);
                    resp = resp(FL+1:end);
                    trig = trig(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = 0;
            end
            % remove NaNs
            x = x(~isnan(resp));
            trig = trig(~isnan(resp));
            resp = resp(~isnan(resp));
            
            % invert signals depending on selection
            switch app.InvertDropDown.Value
                case 'None'
                    heart = resp;
                case 'Respiration'
                    heart = resp;
                    resp = 5-resp;
                case 'Heart'
                    heart = 5-resp;
                case 'Both'
                    resp = 5-resp;
                    heart = resp;
            end

            %% update displays
            if app.AlgorithmCheckBox.Value
                % get display options
                dispoptR = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
                dispoptH = find(contains(app.HRDetectDropDown.Items,app.HRDetectDropDown.Value));

                if dispoptR>1 || dispoptH>2
                    % process respiratory signal
                    switch dispoptR
                        case 1 % basic, no processing
                            sig = resp;
                            set(app.RespTrace,'XData',x,'YData',resp);
                        case 2 % Pan-Tompkins modified
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            sig = movmean(abs(filter(b,a,diff(resp)*app.Rate)),round(0.1*app.Rate));
                            set(app.RespTrace,'XData',x(1:end-1),'YData',sig);
                        case 3 % Pan-Tompkins original
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            sig = movmean((filter(b,a,diff(resp)*app.Rate).^2),round(0.1*app.Rate));
                            set(app.RespTrace,'XData',x(1:end-1),'YData',sig);
                    end
                    set(app.BreathingDisplay,'XLim',[max(x(end)-TimeLast,0) max(x(end),TimeLast)]);

                    % process heart signal
                    switch dispoptH
                        case {1,2} % basic, no processing
                            sigH = heart;
                            set(app.HeartTrace,'XData',x,'YData',sigH);
                        case 3 % sound processing
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            sigH = filter(c,d,heart-mean(heart,'omitnan'));
                            sigH = movmean(abs(diff(sigH)*app.Rate),round(.02*app.Rate));
                            set(app.HeartTrace,'XData',x(max(ceil(5*app.Rate/280),1):end-1),'YData',sigH(max(ceil(5*app.Rate/280),1):end));
                            app.HeartDisplay.YLimMode = 'auto';
                            if app.HeartDisplay.YLim(2)>2
                                app.HeartDisplay.YLim = [0 2];
                            end
                    end
                    set(app.HeartDisplay,'XLim',[max(x(end)-TimeLast,0) max(x(end),TimeLast)]);
                end
            end

            % overlay original trace on main plot
            set(app.Trace,'XData',x,'YData',resp);
            set(app.TrigTrace,'XData',NaN,'YData',NaN);
            if app.ShowTrig.Value
                yrange = app.VitalsDisplay.YLim;
                range = diff(yrange);
                trig = yrange(1)+0.05*range+trig.*0.9*range;
                set(app.TrigTrace,'XData',x,'YData',trig);
            end
            set(app.VitalsDisplay,'XLim',[max(x(end)-TimeLast,0) max(x(end),TimeLast)]);
            
            %% run peak detection algorithm, get parameters
            if rem(round(timePacket(end),2),1)<=1.2/app.Rate && timePacket(end)>=3.999 % run every second beginning after 3 seconds
                XR = max(round(timePacket(end)-179),3):round(timePacket(end));
                XR = padarray(XR(:),[180-numel(XR) 0],NaN,'pre');
                if(x(1)==-timeLag)
                    idx = find(x>-timeLag,1,'first')-1;
                    x = x(idx:end);
                    resp = resp(idx:end);
                    heart = heart(idx:end);
                end
                if app.AutoProm && timePacket(end)>5
                    EstProminence(app,[]);
                    app.AutoProm = false;
                end
                rpeakDistance = 60*app.Rate/app.MaxBreathingRateEditField.Value;
                rprominence = app.BreathProminenceEditField.Value;
                hpeakDistance = 60*app.Rate/app.MaxHeartRateEditField.Value;
                hprominence = app.HeartProminenceEditField.Value;                
                dispoptR = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
                dispoptH = find(contains(app.HRDetectDropDown.Items,app.HRDetectDropDown.Value));

                %% get respiratory rate: process, detect, calculate
                % process respiratory signal
                if ~exist('sig','var')
                    switch dispoptR
                        case 1 % basic, no processing
                            sig = resp;
                        case 2 % Pan-Tompkins modified
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            sig = movmean(abs(filter(b,a,diff(resp)*app.Rate)),round(0.1*app.Rate));
                        case 3 % Pan-Tompkins original
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            sig = movmean((filter(b,a,diff(resp)*app.Rate).^2),round(0.1*app.Rate));
                    end
                end

                % detect peak locations
                [~,rlocs] = findpeaks(sig,'MinPeakDistance',rpeakDistance,'MinPeakProminence',rprominence);
                if app.AlgorithmCheckBox.Value
                    rpks = sig(rlocs);
                else
                    rpks = resp(rlocs);
                end
                
                % determine breathing rate
                if numel(rlocs)>1
                    RR = round(mean(60*app.Rate./diff(rlocs)),1);
                    app.BreathingRateEditField.Value = RR;
                elseif isscalar(rlocs)
                    RR = round(60./min(max(x,[],'omitnan'),TimeLast),1);
                    app.BreathingRateEditField.Value = RR;
                else
                    RR = 0;
                    app.BreathingRateEditField.Value = RR;
                end
                app.RRdata(1) = RR;
                app.RRdata = circshift(app.RRdata,-1,1);
                

                %% get heart rate: process, detect, calculate
                % process heart signal
                if ~exist('sigH','var')
                    switch dispoptH
                        case {1,2} % basic, no processing
                            sigH = heart;
                            if any(contains({'Respiration','Heart'},app.InvertDropDown.Value)) && ~app.HeartDisplay.Visible
                                app.HeartPeaks.Marker = '^';
                            else
                                app.HeartPeaks.Marker = 'v';
                            end
                        case 3 % sound processing
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            sigH = filter(c,d,heart-mean(heart,'omitnan'));
                            sigH = movmean(abs(diff(sigH)*app.Rate),round(.02*app.Rate));
                            app.HeartPeaks.Marker = 'v';
                    end
                end

                % detect heartbeats
                switch dispoptH
                    case {1,2} % basic detection, median and mean determined later
                        if dispoptR==3
                            rprominence = sqrt(rprominence)/10;
                        elseif dispoptR==2
                            rprominence = rprominence/10;
                        end
                        [~,rlocs2] = findpeaks(sigH,'MinPeakDistance',rpeakDistance,'MinPeakProminence',rprominence);
                        [~,hlocs] = findpeaks(sigH,'MinPeakDistance',hpeakDistance,'MinPeakProminence',hprominence);
                        if app.HeartDisplay.Visible
                            hpks = heart(hlocs);
                        else
                            hpks = resp(hlocs);
                        end
                        [~,idx] = intersect(hlocs,rlocs2);
                        hlocs(idx) = NaN;
                    case 3 % high-frequency-based "sound" detection
                        if ~exist('sigH','var')
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            heart = filter(c,d,resp-mean(resp,'omitnan'));
                            sigH = movmean(abs(diff(heart)*app.Rate),round(.02*app.Rate));
                        end
                        [~,hlocs] = findpeaks(sigH,'MinPeakDistance',hpeakDistance,'MinPeakProminence',hprominence*10);
                        if app.AlgorithmCheckBox.Value
                            hpks = sigH(hlocs);
                        else
                            hpks = resp(hlocs);
                        end
                end
                
                % determine heart rate
                if length(hlocs)>1
                    HR = app.Rate*60./diff(hlocs);
                    HR(isnan(HR)) = [];
                    if isempty(HR)
                        HR = 0;
                    else
                        if contains(app.HRDetectDropDown.Value,'Median')
                            HR = round(median(HR,'omitnan'));
                        else
                            HR = round(mean(HR(~isoutlier(HR)),'omitnan'),1);
                        end
                    end
                    app.HeartRateEditField.Value = HR;
                elseif isscalar(hlocs)
                    HR = round(60./min(max(x,[],'omitnan'),TimeLast));
                    HR(isnan(HR)) = [];
                    if length(HR)<1
                        HR=0;
                    end
                    app.HeartRateEditField.Value = HR;
                else
                    HR = 0;
                    app.HeartRateEditField.Value = HR;
                end
                app.HRdata(1) = HR;
                app.HRdata = circshift(app.HRdata,-1,1);

                %% display vital rates and peaks
                if app.ShowRatesCheckBox.Value
                    Times = XR(~isnan(XR));
                    Xrange = [Times(1) Times(end)];
                    if isscalar(Times) || diff(Xrange)<app.DisplayRatesEditField.Value
                        xlim = Xrange(1)+[-0.1 app.DisplayRatesEditField.Value+0.1];
                    else
                        xlim = [Times(end)-app.DisplayRatesEditField.Value-0.1 max(Times(end),app.DisplayRatesEditField.Value)+0.1];
                    end
                    set(app.HRtrace,'YData',app.HRdata(~isnan(app.HRdata)),'XData',XR(~isnan(app.HRdata)));
                    app.HRRateAxes.XLim = xlim;
                    set(app.RRtrace,'YData',app.RRdata(~isnan(app.RRdata)),'XData',XR(~isnan(app.RRdata)));
                    app.RRRateAxes.XLim = xlim;
                end
                
                rlocs = x(rlocs);
                hpks = hpks(~isnan(hlocs));
                hlocs = x(hlocs(~isnan(hlocs)));
                if app.ShowPeaksCheckBox.Value
                    if isempty(rlocs)
                        set(app.RespPeaks,'XData',NaN,'YData',NaN);
                    else
                        set(app.RespPeaks,'XData',rlocs,'YData',rpks);
                    end
                    if isempty(hlocs)
                        set(app.HeartPeaks,'XData',NaN,'YData',NaN);
                    else
                        set(app.HeartPeaks,'XData',hlocs,'YData',hpks);
                    end
                else
                    set(app.RespPeaks,'XData',NaN,'YData',NaN);
                    set(app.HeartPeaks,'XData',NaN,'YData',NaN);
                end
                
                if app.LogRatestoFileCheckBox.Value
                    fprintf(app.logid,'%s,%.1f,%.0f\n',datetime('now','Format','HH:mm:ss'),RR,HR);
                    %                     dlmwrite(app.logid,[RR HR],'-append');
                end
            end
        end
        
        
    end
    
    methods(Static)
        function result = getSpecialFolder(arg)
            if ispc
            specialFolderType = System.Type.GetType(...
                'System.Environment+SpecialFolder');
            folders = System.Enum.GetValues(specialFolderType);
            enumArg = [];
            for i=1:folders.Length
                if strcmp(char(folders(i)),arg)
                    enumArg = folders(i);
                    break;
                end
            end
            if isempty(enumArg)
                error('Invalid Argument');
            end
            result = char(System.Environment.GetFolderPath(enumArg));
            elseif ismac
                pathname = cd;
                idx = strfind(pathname,filesep);
                result = [pathname(1:idx(3)) arg(3:end)];
            end
        end
        function devices = IDSerialComs()
            devices = [];
            if ispc
                Skey = 'HKEY_LOCAL_MACHINE\HARDWARE\DEVICEMAP\SERIALCOMM';
                [~, list] = dos(['REG QUERY ' Skey]);
                if ischar(list) && strcmp('ERROR',list(1:5))
                    return;
                end
                list = strread(list,'%s','delimiter',' '); %#ok<FPARK> requires strread()
                coms = 0;
                for i = 1:numel(list)
                    if strcmp(list{i}(1:3),'COM')
                        if ~iscell(coms)
                            coms = list(i);
                        else
                            coms{end+1} = list{i}; %#ok<AGROW> Loop size is always small
                        end
                    end
                end
                key = 'HKEY_LOCAL_MACHINE\SYSTEM\CurrentControlSet\Enum\USB\';
                [~, vals] = dos(['REG QUERY ' key ' /s /f "FriendlyName" /t "REG_SZ"']);
                if ischar(vals) && strcmp('ERROR',vals(1:5))
                    return;
                end
                vals = textscan(vals,'%s','delimiter','\t');
                vals = cat(1,vals{:});
                out = 0;
                for i = 1:numel(vals)
                    if strcmp(vals{i}(1:min(12,end)),'FriendlyName')
                        if ~iscell(out)
                            out = vals(i);
                        else
                            out{end+1} = vals{i}; %#ok<AGROW> Loop size is always small
                        end
                    end
                end
                for i = 1:numel(coms)
                    match = strfind(out,[coms{i},')']);
                    ind = 0;
                    for j = 1:numel(match)
                        if ~isempty(match{j})
                            ind = j;
                        end
                    end
                    if ind ~= 0
                        com = str2double(coms{i}(4:end));
                        if com > 9
                            length = 8;
                        else
                            length = 7;
                        end
                        devices{i,1} = out{ind}(27:end-length); %#ok<AGROW>
                        devices{i,2} = ['COM' int2str(com)]; %#ok<AGROW> Loop size is always small
                    end
                end
            elseif ismac
                try
                    portNames = ls('/dev/cu.usbmodem*');

                    spacers = strfind(portNames,9);
                    numPorts = numel(spacers)+1;
                    if numPorts==1
                        devices{1,1} = 'Arduino';
                        devices{1,2} = portNames(1:end-1);
                    else
                        devices{1,1} = 'Arduino';
                        devices{1,2} = portNames(1:spacers(1)-1);
                        for n=2:numPorts-1
                            devices{n,1} = 'Arduino';
                            devices{n,2} = portNames(spacers(n)+1:spacers(n+1)-1);
                        end
                        devices{numPorts,1} = 'Arduino';
                        devices{numPorts,2} = portNames(spacers(numPorts-1)+1:end-1);
                    end
                catch
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
            % for first run, makes ProgramData file if on PC. No action for
            % Mac
            if ispc
                rootdir = fullfile(app.getSpecialFolder('CommonApplicationData'),'MousePZT');
                SettingsFile = fullfile(rootdir,'settings.mat');
                if ~exist(rootdir,'dir')
                    mkdir(rootdir);
                end
                if ~exist(SettingsFile,'file')
                    % ask user if the default settings should be for 50Hz
                    % or 60Hz
                    selection = uiconfirm(app.BreathingSensorUIFigure,'There was no prior settings file found. For filtering purposes, what is the AC line frequency?',...
                        'Breathing Sensor','Options',{'50 Hz','60 Hz','Cancel'},'Icon','info');
                    if strcmp(selection,'Cancel') || isempty(selection)
                        Close(app,[]);
                        return
                    end
                    if strcmp(selection,'50 Hz')
                        app.FilterDropDown.Value = '50Hz Mov Avg';
                        app.SampleRateEditField .Value = 1000;
                    elseif strcmp(selection,'60 Hz')
                        app.FilterDropDown.Value = '60Hz Mov Avg';
                        app.SampleRateEditField.Value = 1200;
                    end
                else
                    startupsettings = load(SettingsFile);
                    app.FilterDropDown.Value = startupsettings.FilterValue;
                    app.SampleRateEditField.Value = startupsettings.SampleValue;
                    app.ShowPeaksCheckBox.Value = startupsettings.ShowPeaks;
                    app.AlgorithmCheckBox.Value = startupsettings.ShowAlgorithm;
                    app.ShowRatesCheckBox.Value = startupsettings.ShowRates;
                    app.DisplayRatesEditField.Value = startupsettings.DisplayRateTime;
                    if ~app.ShowRatesCheckBox.Value
                        DispRatePlots(app,[]);
                    end
                    % adjust position if display location is not available
                    ScreenPositions = get(0,'MonitorPositions');
                    if startupsettings.Position(1)+startupsettings.Position(3)>ScreenPositions(end,1)+ScreenPositions(end,3)
                        Pos1 = rem(startupsettings.Position(1),ScreenPositions(end,3));
                        Pos2 = ScreenPositions(end,3)-startupsettings.Position(3);
                        startupsettings.Position(1) = min(Pos1,Pos2);
                    end
                    if startupsettings.Position(2)+startupsettings.Position(4)>ScreenPositions(end,2)+ScreenPositions(end,4)
                        Pos1 = rem(startupsettings.Position(2),ScreenPositions(end,4));
                        Pos2 = ScreenPositions(end,4)-startupsettings.Position(4);
                        startupsettings.Position(2) = min(Pos1,Pos2);
                    end
                    app.BreathingSensorUIFigure.Position = startupsettings.Position;
                end
            end
            
            searching = true;
            wbar = uiprogressdlg(app.BreathingSensorUIFigure,'Message',...
                'Looking for Arduino','Title','Breathing Sensor','Indeterminate','on');
            while searching
                Comms = app.IDSerialComs();
                while isa(Comms,'double')
                    val = uiconfirm(app.BreathingSensorUIFigure,'No Arduino detected.','Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Warning');
                    if strcmp(val,'Try again')
                        Comms = app.IDSerialComs();
                    else
                        Close(app,[]);
                        return
                    end
                end
                Comms = reshape(Comms(~cellfun(@isempty,Comms)),[],2);
                while isempty(Comms)
                    val = uiconfirm(app.BreathingSensorUIFigure,'No Arduino detected.','Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Warning');
                    if strcmp(val,'Try again')
                        Comms = app.IDSerialComs();
                    else
                        delete(app);
                        return
                    end
                end
                ArduinoPorts = Comms(contains(Comms(:,1),'Arduino'),2);
                N = numel(ArduinoPorts);
                n=1;
                while n<=N && searching
                    try
                        arduino = serialport(ArduinoPorts{n},115200);
                        attempts = 0; connection = false;
                        pause(0.1);
                        while attempts < 30 && ~connection
                            write(arduino,0,'uint8');
                            drawnow;
                            tic;
                            while ~arduino.NumBytesAvailable && toc<0.1
                                drawnow;
                            end
                            if toc>0.1
                                attempts = attempts+1;
                            else
                                pause(0.1);
                                if arduino.NumBytesAvailable>5
                                    Program = read(arduino,arduino.NumBytesAvailable,'char');
                                    connection = true;
                                end
                            end
                        end
                        if strcmp(Program,'Breathing Sensor With Trigger')
                            searching = false;
                        else
                            n = n+1;
                            delete(arduino);
                        end
                    catch
                        n = n+1;
                    end
                end
                if searching
                    selection = uiconfirm(app.BreathingSensorUIFigure,'No matching Arduino programs found. Try connecting again?',...
                        'Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Error');
                    if strcmp(selection,'Cancel') || isempty(selection)
                        delete(app);
                        return
                    end
                end
            end
            delete(wbar);
            app.Arduino = arduino;
            app.Arduino.InputBufferSize = 1024;

            app.Rate = app.SampleRateEditField.Value;
            app.Trace = line(app.VitalsDisplay,0,0,'Color','k','LineWidth',1);
            app.TrigTrace = line(app.VitalsDisplay,0,0,'Color','r','LineWidth',1);
            app.RespTrace = line(app.BreathingDisplay,0,0,'Color','b','LineWidth',1);
            app.HeartTrace = line(app.HeartDisplay,0,0,'Color','r','LineWidth',0.5);
            app.HRtrace = line(app.HRRateAxes,0,0,'Color','k','LineWidth',1);
            app.RRtrace = line(app.RRRateAxes,0,0,'Color','k','LineWidth',1);
            app.HeartPeaks = line(app.VitalsDisplay,NaN,NaN,'Color','r','Marker','v','MarkerSize',6,'LineStyle','none','MarkerFaceColor','r');
            app.RespPeaks = line(app.VitalsDisplay,NaN,NaN,'Color','k','Marker','o','MarkerSize',8,'LineStyle','none','MarkerFaceColor','k');
            pathname = [app.getSpecialFolder('MyDocuments'),filesep,'MousePZT'];
            if ~isfolder(pathname)
                mkdir(pathname);
            end
            app.PathTextArea.Value = pathname;
            app.VitalsDisplay.YLimMode = 'auto';
            app.show_algorithm = false;
        end

        % Value changed function: SampleRateEditField
        function SetSampleRate(app, event)
            value = app.SampleRateEditField.Value;
            app.Rate = value;
        end

        % Value changed function: StartButton
        function StartStopRecording(app, event)
            value = app.StartButton.Value;
            if value
                app.AutoProm = true;
                % create binary file to record to
                if app.SavetoFileCheckBox.Value
                    if isempty(app.FileEditField.Value)
                        questdlg('Please enter a name for the file or deselect the record to file option','Breathing Sensor','Ok','Ok');
                        app.StartButton.Value = false;
                        return;
                    else
                        app.FileEditField.Enable = false;
                    end
                    filename = [app.PathTextArea.Value{:} app.FileEditField.Value '.bin'];
                    if exist(filename,'file')
                        q = questdlg('A file with the same name already exists. Overwrite?','Breathing Sensor','Yes','No','No');
                        if not(strcmp(q,'Yes'))
                            app.StartButton.Value = false;
                            app.StartButton.Text = 'Start';
                            app.SavetoFileCheckBox.Enable = true;
                            app.SampleRateEditField.Enable = true;
                            return
                        end
                    end
                    app.FID = fopen(filename,'w');
                    fwrite(app.FID,now,'double'); %#ok<*TNOW1> 
                    fwrite(app.FID,app.Rate,'double');
                    fwrite(app.FID,1,'uint8');
                    %                     fwrite(app.FID,4,'uint8'); fwrite(app.FID,'Time','char');
                    fwrite(app.FID,6,'uint8'); fwrite(app.FID,'Signal','char');
                end
                % create vitals log file to record to
                if app.LogRatestoFileCheckBox.Value
                    if isempty(app.FileEditField.Value) && ~app.SavetoFileCheckBox.Value
                        questdlg('Please enter a name for the file or deselect the log to file option','Breathing Sensor','Ok','Ok');
                        app.StartButton.Value = false;
                        return;
                    else
                        app.FileEditField.Enable = false;
                    end
                    logFile = [app.PathTextArea.Value{:} app.FileEditField.Value '.csv'];
                    if exist(logFile,'file')
                        q = questdlg('A file with the same name already exists. Overwrite?','Breathing Sensor','Yes','No','No');
                        if not(strcmp(q,'Yes'))
                            app.StartButton.Value = false;
                            app.StartButton.Text = 'Start';
                            app.SavetoFileCheckBox.Enable = true;
                            app.SampleRateEditField.Enable = true;
                            return
                        end
                    end
                    cHeader = {'Time','Breathing Rate','Heart Rate'};
                    textHeader = strjoin(cHeader,',');
                    app.logid = fopen(logFile,'w');
                    fprintf(app.logid,'%s\n',datetime('now','Format','yyyy-MMM-dd'));
                    fprintf(app.logid,'%s\n',textHeader);
                end
                % diable all that could cause an error
                app.SavetoFileCheckBox.Enable = false;
                app.LogRatestoFileCheckBox.Enable = false;
                app.SampleRateEditField.Enable = false;
                app.ToggleNumberEditField.Enable = false;
                app.Trigger.Enable = false;
                app.StartButton.Text = 'Stop';

                % reset time and data vectors
                set(app.HeartPeaks,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'XData',NaN,'YData',NaN);
                set(app.HRtrace,'XData',NaN,'YData',NaN);
                set(app.RRtrace,'XData',NaN,'YData',NaN);
                app.Data = NaN(ceil(app.Rate*30),1);
                app.RRdata = NaN(180,1);
                app.HRdata = NaN(180,1);
                app.TrigData = NaN(ceil(app.Rate*30),1);
                app.Time = zeros(ceil(app.Rate*30),1);
                % send interval spacing between samples in microseconds
                while app.Arduino.NumBytesAvailable
                    read(app.Arduino,app.Arduino.NumBytesAvailable,'uint8');
                    pause(0.1);
                end
                correct_interval = false;
                while ~correct_interval
                    us = max(round(1e6/app.Rate),1);
                    write(app.Arduino,[1 fliplr(double(typecast(uint32(us),'uint8')))],'uint8');
                    pause(0.1);
                    if app.Arduino.NumBytesAvailable
                        output = read(app.Arduino,app.Arduino.NumBytesAvailable,'char');
                        interval = str2double(output);
                        if interval == us
                            correct_interval = true;
                        else
                            if app.Arduino.NumBytesAvailable
                                read(app.Arduino,app.Arduino.NumBytesAvailable,'char');
                            end
                        end
                    end
                end
                recordOpt = find(contains(app.Trigger.Items,app.Trigger.Value));
                value = app.Rate;
                configureCallback(app.Arduino,'byte',min(max(20,round(value/10)*2),500),@(src,event) recordData(app,src,event));
                if recordOpt > 3
                    write(app.Arduino,[6 app.ToggleNumberEditField.Value],'uint8');
                else
                    write(app.Arduino,1+recordOpt,'uint8'); % command to start recording
                end
            else
                stopping = true;
                while stopping
                    write(app.Arduino,255,'uint8');
                    pause(0.1);
                    tic
                    while app.Arduino.NumBytesAvailable && toc<1
                        counts = app.Arduino.NumBytesAvailable/2;
                        if counts>1
                            packet = read(app.Arduino,counts,'uint16');
                            if app.SavetoFileCheckBox.Value
                                fwrite(app.FID,uint16(packet),'uint16');
                            end
                        elseif app.Arduino.NumBytesAvailable
                            read(app.Arduino,app.Arduino.NumBytesAvailable,'uint8')
                        end
                        pause(0.1);
                    end
                    if toc<1
                        stopping = false;
                        if app.SavetoFileCheckBox.Value
                            fclose(app.FID);
                        end
                        if app.LogRatestoFileCheckBox.Value
                            fclose(app.logid);
                        end
                    end
                end
                if ~isempty(app.FileEditField.Value)
                    app.SavetoFileCheckBox.Enable = true;
                    app.LogRatestoFileCheckBox.Enable = true;
                end
                app.FileEditField.Enable = true;
                app.SampleRateEditField.Enable = true;
                app.ToggleNumberEditField.Enable = true;
                app.Trigger.Enable = true;
                app.StartButton.Text = 'Start';
            end
        end

        % Value changed function: LowerEditField, UpperEditField
        function SetYRange(app, event)
            value(2) = round(app.UpperEditField.Value,2);
            if value(2)>5.1,value(2) = 5.1;end
            value(1) = round(app.LowerEditField.Value,2);
            if value(1)<-0.1,value(1) = -0.1; end
            if value(1)>=value(2)
                app.LowerEditField.Value = app.VitalsDisplay.YLim(1);
                app.UpperEditField.Value = app.VitalsDisplay.YLim(2);
            else
                app.VitalsDisplay.YLim = value;
                app.VitalsDisplay.YLimMode = 'manual';
                app.AutoRangeCheckBox.Value = false;
            end
        end

        % Value changed function: AutoRangeCheckBox
        function AutoRangeYAxis(app, event)
            value = app.AutoRangeCheckBox.Value;
            if value
                app.VitalsDisplay.YLimMode = 'auto';
            else
                app.VitalsDisplay.YLimMode = 'manual';
                app.VitalsDisplay.YLim = [app.LowerEditField.Value app.UpperEditField.Value];
            end
        end

        % Button pushed function: FileSelectButton
        function ChooseFile(app, event)
            [file,path] = uiputfile([app.PathTextArea.Value{:} '*.bin']);
            if ischar(file)
                if ~app.StartButton.Value
                    app.SavetoFileCheckBox.Enable = true;
                    app.LogRatestoFileCheckBox.Enable = true;
                end
                app.PathTextArea.Value = path;
                if contains(file,'.')
                    idx = find(file=='.',1,'last');
                    app.FileEditField.Value = file(1:idx-1);
                else
                    app.FileEditField.Value = file;
                end
            end
        end

        % Close request function: BreathingSensorUIFigure
        function Close(app, event)
            try
                delete(app.Arduino);
            catch
            end
            if ispc
                rootdir = fullfile(app.getSpecialFolder('CommonApplicationData'),'MousePZT');
                SettingsFile = fullfile(rootdir,'settings.mat');

                startupsettings.FilterValue = app.FilterDropDown.Value;
                startupsettings.SampleValue = app.SampleRateEditField.Value;
                startupsettings.ShowPeaks = app.ShowPeaksCheckBox.Value;
                startupsettings.ShowAlgorithm = app.AlgorithmCheckBox.Value;
                startupsettings.ShowRates = app.ShowRatesCheckBox.Value;
                startupsettings.DisplayRateTime = app.DisplayRatesEditField.Value;
                startupsettings.Position = app.BreathingSensorUIFigure.Position;
                save(SettingsFile,'-struct','startupsettings');
            end
            pause(0.5);
            delete(app)
        end

        % Value changed function: FileEditField
        function FileNameAvailable(app, event)
            if ~app.StartButton.Value
                value = ~isempty(app.FileEditField.Value);
                app.LogRatestoFileCheckBox.Enable = value;
                app.SavetoFileCheckBox.Enable = value;
                if ~value
                    app.LogRatestoFileCheckBox.Value = false;
                    app.SavetoFileCheckBox.Value = false;
                end
            end
        end

        % Button pushed function: ProminenceButton
        function EstProminence(app, event)
            data = get(app.Trace,'YData');
            data(isnan(data)) = [];
            if ~isempty(data)
                midVal = (median(data)+mean(data))/2;
                datasort = sort(data,'descend');
                N = length(data);
                maxVal = mean(datasort(1:2*ceil(N/100)));
                respprom = (maxVal-midVal)/sqrt(2);
                if ~app.AlgorithmCheckBox.Value
                    opt = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
                    if opt==2
                        respprom = respprom*10;
                    elseif opt==3
                        respprom = (respprom*10)^2;
                    end
                end
                heartprom = median(abs(data-median(data))); % using median of absolute differences (MAD)
                app.HeartProminenceEditField.Value = round(max(heartprom,app.HeartProminenceEditField.Limits(1)),3); % heart prominence will be estimated if the algorithm is not displayed
                app.BreathProminenceEditField.Value = round(max(respprom,app.BreathProminenceEditField.Limits(1)),3);
                app.AutoProm = false;
            end
        end

        % Value changed function: RRDetectDropDown
        function PeakDetection(app, event)
            lastVal = find(contains(app.RRDetectDropDown.Items,event.PreviousValue));
            opt = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
            value = app.AlgorithmCheckBox.Value;
            if value && ~strcmp(app.RRDetectDropDown.Value,'Basic Peaks') && ~app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)/2);
                app.BreathingDisplay.Visible = true;
                app.HeartDisplay.Visible = true;
                app.RespPeaks.Parent = app.BreathingDisplay;
                app.HeartPeaks.Parent = app.HeartDisplay;
                app.RespPeaks.MarkerSize = 4;
                app.HeartPeaks.MarkerSize = 3;
                app.show_algorithm = true;
            elseif (~value || strcmp(app.RRDetectDropDown.Value,'Basic Peaks')) && app.show_algorithm && contains(app.HRDetectDropDown.Value,'Basic')
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)*2);
                app.BreathingDisplay.Visible = false;
                app.HeartDisplay.Visible = false;
                app.RespPeaks.Parent = app.VitalsDisplay;
                app.HeartPeaks.Parent = app.VitalsDisplay;
                set(app.HeartTrace,'XData',NaN,'YData',NaN);
                set(app.RespTrace,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'MarkerSize',8,'XData',NaN,'YData',NaN);
                set(app.HeartPeaks,'MarkerSize',6,'XData',NaN,'YData',NaN);
                app.show_algorithm = false;
            end
            switch opt
                case 1 % basic peaks
                    if lastVal == 3
                        app.BreathProminenceEditField.Value = min(max(sqrt(app.BreathProminenceEditField.Value)/10,0.005),5);
                    elseif lastVal == 2
                        app.BreathProminenceEditField.Value = min(max(app.BreathProminenceEditField.Value/10,0.005),5);
                    end
                    app.BreathProminenceEditField.Limits = [0.005 5];
                case 2 % PanTompkins Modified
                    if lastVal == 3
                        app.BreathProminenceEditField.Value = min(max(sqrt(app.BreathProminenceEditField.Value),0.05),50);
                        app.BreathProminenceEditField.Limits = [0.05 50];
                    elseif lastVal == 1
                        app.BreathProminenceEditField.Limits = [0.005 50];
                        app.BreathProminenceEditField.Value = min(max(app.BreathProminenceEditField.Value*10,0.05),50);
                    end
                case 3 % PanTompkins Original
                    app.BreathProminenceEditField.Limits = [0.05 250];
                    if lastVal == 2
                        app.BreathProminenceEditField.Value = min(max(app.BreathProminenceEditField.Value.^2,0.05),250);
                    elseif lastVal == 1
                        app.BreathProminenceEditField.Value = min(max((app.BreathProminenceEditField.Value*10)^2,0.05),250);
                    end
            end
        end

        % Value changed function: AlgorithmCheckBox
        function ShowAlgorithm(app, event)
            value = app.AlgorithmCheckBox.Value;
            if value && (~contains(app.RRDetectDropDown.Value,'Basic') || ~contains(app.HRDetectDropDown.Value,'Basic'))...
                    && ~app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)/2);
                app.BreathingDisplay.Visible = true;
                app.HeartDisplay.Visible = true;
                app.RespPeaks.Parent = app.BreathingDisplay;
                app.HeartPeaks.Parent = app.HeartDisplay;
                app.RespPeaks.MarkerSize = 4;
                app.HeartPeaks.MarkerSize = 3;
                app.show_algorithm = true;
            elseif (~value || strcmp(app.RRDetectDropDown.Value,'Basic')) && app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)*2);
                app.BreathingDisplay.Visible = false;
                app.HeartDisplay.Visible = false;
                app.RespPeaks.Parent = app.VitalsDisplay;
                app.HeartPeaks.Parent = app.VitalsDisplay;
                set(app.HeartTrace,'XData',NaN,'YData',NaN);
                set(app.RespTrace,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'MarkerSize',8,'XData',NaN,'YData',NaN);
                set(app.HeartPeaks,'MarkerSize',6,'XData',NaN,'YData',NaN);
                app.show_algorithm = false;
            end
        end

        % Value changed function: Trigger
        function TriggerToggle(app, event)
            value = app.Trigger.Value;
            app.ShowTrig.Visible = false;
            if strcmp(value,'Toggle') || strcmp(value,'Per Sample')
                app.ToggleNumberEditField.Visible = true;
            elseif strcmp(value,'External')
                app.ShowTrig.Visible = true;
            else
                app.ToggleNumberEditField.Visible = false;
            end
        end

        % Value changed function: HRDetectDropDown
        function HeartDetection(app, event)
            value = app.AlgorithmCheckBox.Value;
            if value && ~contains(app.HRDetectDropDown.Value,'Basic') && ~app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)/2);
                app.BreathingDisplay.Visible = true;
                app.HeartDisplay.Visible = true;
                app.RespPeaks.Parent = app.BreathingDisplay;
                app.HeartPeaks.Parent = app.HeartDisplay;
                app.RespPeaks.MarkerSize = 4;
                app.HeartPeaks.MarkerSize = 3;
                app.show_algorithm = true;
            elseif (~value || contains(app.HRDetectDropDown.Value,'Basic')) && app.show_algorithm && contains(app.RRDetectDropDown.Value,'Basic')
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)*2);
                app.BreathingDisplay.Visible = false;
                app.HeartDisplay.Visible = false;
                app.RespPeaks.Parent = app.VitalsDisplay;
                app.HeartPeaks.Parent = app.VitalsDisplay;
                set(app.HeartTrace,'XData',NaN,'YData',NaN);
                set(app.RespTrace,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'MarkerSize',8,'XData',NaN,'YData',NaN);
                set(app.HeartPeaks,'MarkerSize',6,'XData',NaN,'YData',NaN);
                app.show_algorithm = false;
            end
        end

        % Value changed function: ShowTrig
        function DisplayTrigger(app, event)
            value = app.ShowTrig.Value;
            if ~value
                set(app.TrigTrace,'YData',NaN,'XData',NaN);
            end
        end

        % Value changed function: ShowRatesCheckBox
        function DispRatePlots(app, event)
            value = app.ShowRatesCheckBox.Value;
            app.RRRateAxes.Visible = value;
            app.HRRateAxes.Visible = value;
            app.ResetRatesButton.Visible = value;
            app.DisplayRatesEditField.Visible = value;
            app.DisplayRatesEditFieldLabel.Visible = value;
            app.secLabel.Visible = value;
            if value % make plots visible, change sign direction of shift
                shiftY = -150;
            else
                shiftY = 150;
                set(app.RRtrace,'XData',NaN','YData',NaN);
                set(app.HRtrace,'XData',NaN,'YData',NaN);
            end
            app.BreathingSensorUIFigure.Position(4) = app.BreathingSensorUIFigure.Position(4)-shiftY;
            app.BreathingSensorUIFigure.Position(2) = app.BreathingSensorUIFigure.Position(2)+shiftY;
            elements = {'StartButton','BreathingRateEditField','BreathingRateEditFieldLabel',...
                'HeartRateLabel','HeartRateEditField','minLabel','minLabel_2',...
                'InvertDropDown','InvertDropDownLabel',...
                'AlgorithmCheckBox','ShowRatesCheckBox','ShowPeaksCheckBox','ShowTrig',...
                'BreathingDisplay','HeartDisplay','VitalsDisplay'};
            for n=1:length(elements)
                app.(elements{n}).Position(2) = app.(elements{n}).Position(2)-shiftY;
            end
        end

        % Button pushed function: ResetRatesButton
        function ResetHRRR(app, event)
            app.HRdata = app.HRdata.*NaN;
            app.RRdata = app.RRdata.*NaN;
        end

        % Callback function
        function InvertUpdate(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BreathingSensorUIFigure = uifigure('Visible', 'off');
            app.BreathingSensorUIFigure.Name = 'Breathing Sensor';
            app.BreathingSensorUIFigure.CloseRequestFcn = createCallbackFcn(app, @Close, true);
            app.VitalsDisplay = uiaxes(app.BreathingSensorUIFigure);
            xlabel(app.VitalsDisplay, 'Time (s)')
            ylabel(app.VitalsDisplay, 'Volts')
            app.VitalsDisplay.XLim = [0 5];
            app.VitalsDisplay.YLim = [0 5];
            app.BreathingDisplay = uiaxes(app.BreathingSensorUIFigure);
            title(app.BreathingDisplay, 'Breathing')
            xlabel(app.BreathingDisplay, 'Time (s)')
            app.BreathingDisplay.XLim = [0 5];
            app.BreathingDisplay.Visible = 'off';
            app.HeartDisplay = uiaxes(app.BreathingSensorUIFigure);
            title(app.HeartDisplay, 'Heart')
            xlabel(app.HeartDisplay, 'Time (s)')
            app.HeartDisplay.XLim = [0 5];
            app.HeartDisplay.Visible = 'off';
            app.RRRateAxes = uiaxes(app.BreathingSensorUIFigure);
            xlabel(app.RRRateAxes, 'Time (s)')
            ylabel(app.RRRateAxes, 'RR (min^-^1)')
            zlabel(app.RRRateAxes, 'Z')
            app.HRRateAxes = uiaxes(app.BreathingSensorUIFigure);
            xlabel(app.HRRateAxes, 'Time (s)')
            ylabel(app.HRRateAxes, 'HR (min^-^1)')
            zlabel(app.HRRateAxes, 'Z')
            app.Panel = uipanel(app.BreathingSensorUIFigure);
            app.AutoRangeCheckBox = uicheckbox(app.Panel);
            app.AutoRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRangeYAxis, true);
            app.AutoRangeCheckBox.Tooltip = {'Autoscales y-axis to show piezo signal'};
            app.AutoRangeCheckBox.Text = 'Auto Range';
            app.AutoRangeCheckBox.Value = true;
            app.FileSelectButton = uibutton(app.Panel, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFile, true);
            app.FileSelectButton.Text = '...';
            app.SavetoFileCheckBox = uicheckbox(app.Panel);
            app.SavetoFileCheckBox.Enable = 'off';
            app.SavetoFileCheckBox.Text = 'Save to File';
            app.LogRatestoFileCheckBox = uicheckbox(app.Panel);
            app.LogRatestoFileCheckBox.Enable = 'off';
            app.LogRatestoFileCheckBox.Text = 'Log Rates';
            app.MaxHeartRateEditField = uieditfield(app.Panel, 'numeric');
            app.MaxHeartRateEditField.Limits = [10 1500];
            app.MaxHeartRateEditField.Value = 900;
            app.HeartProminenceEditField = uieditfield(app.Panel, 'numeric');
            app.HeartProminenceEditField.Limits = [0.01 5];
            app.HeartProminenceEditField.Value = 0.02;
            app.ProminenceButton = uibutton(app.Panel, 'push');
            app.ProminenceButton.ButtonPushedFcn = createCallbackFcn(app, @EstProminence, true);
            app.ProminenceButton.Tooltip = {'Estimates Prominences for Respiratory and Cardiac signals'};
            app.ProminenceButton.Text = 'Prominence';
            app.rpmLabel = uilabel(app.Panel);
            app.rpmLabel.Text = 'rpm';
            app.bpmLabel = uilabel(app.Panel);
            app.bpmLabel.Text = 'bpm';
            app.ToggleNumberEditField = uieditfield(app.Panel, 'numeric');
            app.ToggleNumberEditField.Limits = [1 255];
            app.ToggleNumberEditField.RoundFractionalValues = 'on';
            app.ToggleNumberEditField.Visible = 'off';
            app.ToggleNumberEditField.Value = 10;
            app.RateHzLabel = uilabel(app.Panel);
            app.RateHzLabel.Text = 'Rate (Hz)';
            app.SampleRateEditField = uieditfield(app.Panel, 'numeric');
            app.SampleRateEditField.Limits = [1 5000];
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SetSampleRate, true);
            app.SampleRateEditField.Value = 1000;
            app.TimesEditFieldLabel = uilabel(app.Panel);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimeEditField = uieditfield(app.Panel, 'numeric');
            app.TimeEditField.Limits = [1 20];
            app.TimeEditField.Value = 5;
            app.UpperEditFieldLabel = uilabel(app.Panel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.Panel, 'numeric');
            app.UpperEditField.Limits = [0 5.1];
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @SetYRange, true);
            app.UpperEditField.Value = 5;
            app.LowerEditFieldLabel = uilabel(app.Panel);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.Panel, 'numeric');
            app.LowerEditField.Limits = [-0.1 5];
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @SetYRange, true);
            app.FileEditFieldLabel = uilabel(app.Panel);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.Panel, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameAvailable, true);
            app.PathTextAreaLabel = uilabel(app.Panel);
            app.PathTextAreaLabel.Text = 'Path';
            app.PathTextArea = uitextarea(app.Panel);
            app.ProminenceEditFieldLabel = uilabel(app.Panel);
            app.ProminenceEditFieldLabel.Text = 'Prominence';
            app.BreathProminenceEditField = uieditfield(app.Panel, 'numeric');
            app.BreathProminenceEditField.Limits = [0.05 5];
            app.BreathProminenceEditField.Value = 0.2;
            app.MaxRateLabel = uilabel(app.Panel);
            app.MaxRateLabel.Text = 'Max Rate';
            app.MaxBreathingRateEditField = uieditfield(app.Panel, 'numeric');
            app.MaxBreathingRateEditField.Limits = [10 300];
            app.MaxBreathingRateEditField.Value = 240;
            app.FilterDropDownLabel = uilabel(app.Panel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.Panel);
            app.FilterDropDown.Items = {'None', '20Hz Mov Avg', '40Hz Mov Avg', '50Hz Mov Avg', '60Hz Mov Avg', '50Hz Lowpass', '100Hz Lowpass'};
            app.FilterDropDown.Tooltip = {'Optional filters for data visualization'};
            app.FilterDropDown.Value = '50Hz Mov Avg';
            app.RRDetectDropDownLabel = uilabel(app.Panel);
            app.RRDetectDropDownLabel.Text = 'RR Detect';
            app.RRDetectDropDown = uidropdown(app.Panel);
            app.RRDetectDropDown.Items = {'Basic Peaks', 'Pan-Tompkins (Modified)', 'Pan-Tompkins (Original)'};
            app.RRDetectDropDown.ValueChangedFcn = createCallbackFcn(app, @PeakDetection, true);
            app.RRDetectDropDown.Value = 'Basic Peaks';
            app.TriggerLabel = uilabel(app.Panel);
            app.TriggerLabel.Text = 'Trigger';
            app.Trigger = uidropdown(app.Panel);
            app.Trigger.Items = {'None', 'External', 'Session', 'Per Sample', 'Toggle'};
            app.Trigger.ValueChangedFcn = createCallbackFcn(app, @TriggerToggle, true);
            app.Trigger.Value = 'None';
            app.HRDetectDropDownLabel = uilabel(app.Panel);
            app.HRDetectDropDownLabel.Text = 'HR Detect';
            app.HRDetectDropDown = uidropdown(app.Panel);
            app.HRDetectDropDown.Items = {'Basic Median', 'Basic Average', 'Sound'};
            app.HRDetectDropDown.ValueChangedFcn = createCallbackFcn(app, @HeartDetection, true);
            app.HRDetectDropDown.Value = 'Basic Median';
            app.BreathingRateEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.BreathingRateEditFieldLabel.Text = 'Breathing Rate';
            app.BreathingRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.BreathingRateEditField.ValueDisplayFormat = '%11.1f';
            app.BreathingRateEditField.Editable = 'off';
            app.StartButton = uibutton(app.BreathingSensorUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartStopRecording, true);
            app.StartButton.Text = 'Start';
            app.HeartRateLabel = uilabel(app.BreathingSensorUIFigure);
            app.HeartRateLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.HeartRateEditField.ValueDisplayFormat = '%11.0f';
            app.HeartRateEditField.Editable = 'off';
            app.minLabel = uilabel(app.BreathingSensorUIFigure);
            app.minLabel.Text = '/min';
            app.minLabel_2 = uilabel(app.BreathingSensorUIFigure);
            app.minLabel_2.Text = '/min';
            app.ShowPeaksCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.ShowPeaksCheckBox.Text = 'Show Peaks';
            app.ShowPeaksCheckBox.Value = true;
            app.AlgorithmCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.AlgorithmCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAlgorithm, true);
            app.AlgorithmCheckBox.Text = 'Algorithm';
            app.AlgorithmCheckBox.Value = true;
            app.ShowTrig = uicheckbox(app.BreathingSensorUIFigure);
            app.ShowTrig.ValueChangedFcn = createCallbackFcn(app, @DisplayTrigger, true);
            app.ShowTrig.Visible = 'off';
            app.ShowTrig.Text = 'Show Trigger';
            app.ResetRatesButton = uibutton(app.BreathingSensorUIFigure, 'push');
            app.ResetRatesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHRRR, true);
            app.ResetRatesButton.Text = 'Reset Rates';
            app.ShowRatesCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.ShowRatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DispRatePlots, true);
            app.ShowRatesCheckBox.Text = 'Show Rates';
            app.ShowRatesCheckBox.Value = true;
            app.InvertDropDownLabel = uilabel(app.BreathingSensorUIFigure);
            app.InvertDropDownLabel.Text = 'Invert';
            app.InvertDropDown = uidropdown(app.BreathingSensorUIFigure);
            app.InvertDropDown.Items = {'None', 'Respiration', 'Heart', 'Both'};
            app.InvertDropDown.Value = 'None';
            app.DisplayRatesEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.DisplayRatesEditFieldLabel.Text = 'Display Rates';
            app.DisplayRatesEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.DisplayRatesEditField.Limits = [10 180];
            app.DisplayRatesEditField.RoundFractionalValues = 'on';
            app.DisplayRatesEditField.Value = 30;
            app.secLabel = uilabel(app.BreathingSensorUIFigure);
            app.secLabel.Text = 'sec';
            % Show the figure after all components are created
            app.BreathingSensorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BreathingSensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BreathingSensorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BreathingSensorUIFigure)
        end
    end
end