classdef BreathingSensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BreathingSensorUIFigure      matlab.ui.Figure
        HRDetectDropDown             matlab.ui.control.DropDown
        HRDetectDropDownLabel        matlab.ui.control.Label
        ToggleNumberEditField        matlab.ui.control.NumericEditField
        Trigger                      matlab.ui.control.DropDown
        TriggerLabel                 matlab.ui.control.Label
        AlgorithmCheckBox            matlab.ui.control.CheckBox
        RRDetectDropDown             matlab.ui.control.DropDown
        RRDetectDropDownLabel        matlab.ui.control.Label
        bpmLabel                     matlab.ui.control.Label
        rpmLabel                     matlab.ui.control.Label
        ProminenceButton             matlab.ui.control.Button
        FilterDropDown               matlab.ui.control.DropDown
        FilterDropDownLabel          matlab.ui.control.Label
        ShowPeaksCheckBox            matlab.ui.control.CheckBox
        HeartProminenceEditField     matlab.ui.control.NumericEditField
        MaxHeartRateEditField        matlab.ui.control.NumericEditField
        minLabel_2                   matlab.ui.control.Label
        minLabel                     matlab.ui.control.Label
        LogRatestoFileCheckBox       matlab.ui.control.CheckBox
        HeartRateEditField           matlab.ui.control.NumericEditField
        HeartRateLabel               matlab.ui.control.Label
        SavetoFileCheckBox           matlab.ui.control.CheckBox
        StartButton                  matlab.ui.control.StateButton
        BreathingRateEditField       matlab.ui.control.NumericEditField
        BreathingRateEditFieldLabel  matlab.ui.control.Label
        InvertCheckBox               matlab.ui.control.CheckBox
        MaxBreathingRateEditField    matlab.ui.control.NumericEditField
        MaxRateLabel                 matlab.ui.control.Label
        BreathProminenceEditField    matlab.ui.control.NumericEditField
        ProminenceEditFieldLabel     matlab.ui.control.Label
        PathTextArea                 matlab.ui.control.TextArea
        PathTextAreaLabel            matlab.ui.control.Label
        FileSelectButton             matlab.ui.control.Button
        FileEditField                matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        AutoRangeCheckBox            matlab.ui.control.CheckBox
        LowerEditField               matlab.ui.control.NumericEditField
        LowerEditFieldLabel          matlab.ui.control.Label
        UpperEditField               matlab.ui.control.NumericEditField
        UpperEditFieldLabel          matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
        TimesEditFieldLabel          matlab.ui.control.Label
        SampleRateEditField          matlab.ui.control.NumericEditField
        RateHzLabel                  matlab.ui.control.Label
        HeartDisplay                 matlab.ui.control.UIAxes
        BreathingDisplay             matlab.ui.control.UIAxes
        VitalsDisplay                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Rate % sampling rate for Arduino, limited to 5kHz
        Data % data collected from sensor by Arduino
        Time % time data for collection
        Trace % displayed voltage trace from sensor
        FID % binary file identifier
        Arduino % serial port for Arduino communications
        RespPeaks % displayed peak points for respiratory peaks
        HeartPeaks % displayed peak points for heartbeats
        logid % file ID for tracking heart and breathing rates
        RespTrace % displayed processed respiratory signal
        HeartTrace % displayed processed cardiac signal
        show_algorithm % flag indicating if algorithm is being shown
    end
    
    methods (Access = private)
        
        function recordData(app,src,~)
            counts = app.Arduino.BytesAvailableFcnCount/2;
            rawpacket = fread(src,counts,'uint16');
            if app.SavetoFileCheckBox.Value
                fwrite(app.FID,uint16(rawpacket),'uint16');
            end
            
            packet = (rawpacket-2^15*(rawpacket>=2^15))*5/1023;
            app.Data(1:counts) = packet;
            timePacket = app.Time(end)+(1:counts)/app.Rate;
            app.Time(1:counts) = timePacket;
            
            app.Data = circshift(app.Data,-counts,1);
            app.Time = circshift(app.Time,-counts,1);
            
            opt = find(contains(app.FilterDropDown.Items,app.FilterDropDown.Value));
            TimeLast = app.TimeEditField.Value;
            
            switch opt
                case 1 % no filter
                    points = app.TimeEditField.Value*app.Rate;
                    resp = app.Data(end-points+1:end);
                    x = app.Time(end-points+1:end);
                    if app.InvertCheckBox.Value
                        resp = 5-resp;
                    end
                    timeLag = 0;
                case 2 % 20 Hz moving average filter
                    FL = ceil(app.Rate/20);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 3 % 40 Hz moving average filter
                    FL = ceil(app.Rate/40);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 4 % 50 Hz moving average filter
                    FL = ceil(app.Rate/50);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 5 % 60 Hz moving average filter
                    FL = ceil(app.Rate/60);
                    points = app.TimeEditField.Value*app.Rate+FL;
                    resp = app.Data(end-points+1:end);
                    %                     resp = movmean(resp,FL);
                    resp = filter(ones(FL,1)/FL,1,resp);
                    resp = resp(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = FL/2/app.Rate;
                    x = x-timeLag;
                    x = x(FL+1:end);
                case 6 % 50 Hz lowpass filter
                    FL = ceil(app.Rate/50);
                    [b,a] = butter(1,50/(app.Rate/2),'low');
                    points = app.TimeEditField.Value*app.Rate;
                    resp = app.Data(end-points-FL+1:end);
                    if isnan(resp(1))
                        idx = find(~isnan(resp),1,'first');
                        resp(1:idx) = resp(idx);
                    end
                    resp = filter(b,a,resp);
                    resp = resp(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = 0;
                case 7 % 100 Hz lowpass filter
                    FL = ceil(app.Rate/100);
                    [b,a] = butter(1,100/(app.Rate/2),'low');
                    points = app.TimeEditField.Value*app.Rate;
                    resp = app.Data(end-points-FL+1:end);
                    if isnan(resp(1))
                        idx = find(~isnan(resp),1,'first');
                        resp(1:idx) = resp(idx);
                    end
                    resp = filter(b,a,resp);
                    resp = resp(FL+1:end);
                    x = app.Time(end-points+1:end);
                    timeLag = 0;
            end
            % remove NaNs
            x = x(~isnan(resp));
            resp = resp(~isnan(resp));
            
            if app.InvertCheckBox.Value
                resp = 5-resp;
            end
            if app.AlgorithmCheckBox.Value
                dispoptR = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
                dispoptH = find(contains(app.HRDetectDropDown.Items,app.HRDetectDropDown.Value));
                if dispoptR>1 || dispoptH>2
                    switch dispoptR
                        case 1 % basic, no processing
                            set(app.RespTrace,'XData',x,'YData',resp);
                        case 2 % Pan-Tompkins modified
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            sig = movmean(abs(filter(b,a,diff(resp)*app.Rate)),round(0.1*app.Rate));
                            set(app.RespTrace,'XData',x(1:end-1),'YData',sig);
                        case 3 % Pan-Tompkins original
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            sig = movmean((filter(b,a,diff(resp)*app.Rate).^2),round(0.1*app.Rate));
                            set(app.RespTrace,'XData',x(1:end-1),'YData',sig);
                    end
                    set(app.BreathingDisplay,'XLim',[max(x(end)-TimeLast,0) max(x(end),TimeLast)]);
                    
                    switch dispoptH
                        case {1,2} % basic, no processing
                            set(app.HeartTrace,'XData',x,'YData',resp);
                            app.HeartDisplay.YLimMode = 'auto';
                        case 3 % sound processing
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            heart = filter(c,d,resp-mean(resp,'omitnan'));
                            sigH = movmean((diff(heart)*app.Rate).^2,round(.02*app.Rate));
                            set(app.HeartTrace,'XData',x(ceil(5*app.Rate/280):end-1),'YData',sigH(ceil(5*app.Rate/280):end));
                            app.HeartDisplay.YLimMode = 'auto';
                            if app.HeartDisplay.YLim(2)>0.2
                                app.HeartDisplay.YLim = [0 0.2];
                            end
                    end
                    set(app.HeartDisplay,'XLim',[max(x(end)-TimeLast,0) max(x(end),TimeLast)]);
                end
            end
            set(app.Trace,'XData',x,'YData',resp);
            set(app.VitalsDisplay,'XLim',[max(x(end)-TimeLast,0) max(x(end),TimeLast)]);


            if rem(round(timePacket(end),2),1)<=1.2/app.Rate
                if(x(1)==-timeLag)
                    idx = find(x>-timeLag,1,'first')-1;
                    x = x(idx:end);
                    resp = resp(idx:end);
                end
                rpeakDistance = 60*app.Rate/app.MaxBreathingRateEditField.Value;
                rprominence = app.BreathProminenceEditField.Value;
                hpeakDistance = 60*app.Rate/app.MaxHeartRateEditField.Value;
                hprominence = app.HeartProminenceEditField.Value;
                detectOptRR = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
                detectOptHR = find(contains(app.HRDetectDropDown.Items,app.HRDetectDropDown.Value));
                
                % detect breathing
                switch detectOptRR
                    case 1 % basic, no processing
                        sig = resp;
                        [rpks,rlocs] = findpeaks(sig,'MinPeakDistance',rpeakDistance,'MinPeakProminence',rprominence);
                    case 2 % Pan-Tompkins modified
                        if ~exist('sig','var')
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            sig = movmean(abs(filter(b,a,diff(resp)*app.Rate)),round(0.1*app.Rate));
                            heart = filter(c,d,resp-mean(resp,'omitnan'));
                            sigH = movmean(abs(diff(heart)*app.Rate),round(.02*app.Rate));
                        end
                        [~,rlocs] = findpeaks(sig,'MinPeakDistance',rpeakDistance,'MinPeakProminence',rprominence);
                        if app.AlgorithmCheckBox.Value
                            rpks = sig(rlocs);
                        else
                            rpks = resp(rlocs);
                        end
                    case 3 % Pan-Tompkins original
                        if ~exist('sig','var')
                            [b,a] = butter(1,[0.1 10]*2/app.Rate);
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            sig = movmean((filter(b,a,diff(resp)*app.Rate).^2),round(0.1*app.Rate));
                            heart = filter(c,d,resp-mean(resp,'omitnan'));
                            sigH = movmean((diff(heart)*app.Rate).^2,round(.02*app.Rate));
                        end
                        [~,rlocs] = findpeaks(sig,'MinPeakDistance',rpeakDistance,'MinPeakProminence',rprominence);
                        if app.AlgorithmCheckBox.Value
                            rpks = sig(rlocs);
                        else
                            rpks = resp(rlocs);
                        end
                end
                
                % determine breathing rate
                if numel(rlocs)>1
                    RR = round(mean(60*app.Rate./diff(rlocs)),1);
                    app.BreathingRateEditField.Value = RR;
                elseif numel(rlocs)==1
                    RR = round(60./TimeLast,1);
                    app.BreathingRateEditField.Value = RR;
                else
                    RR = 0;
                    app.BreathingRateEditField.Value = RR;
                end
                
                % detect heartbeats
                switch detectOptHR
                    case {1,2} % basic detection, median and mean determined later
                        if detectOptRR>1
                            [~,rlocs2] = findpeaks(resp,'MinPeakDistance',rpeakDistance,'MinPeakProminence',rprominence);
                        else
                            rlocs2 = rlocs;
                        end
                        [hpks,hlocs] = findpeaks(resp,'MinPeakDistance',hpeakDistance,'MinPeakProminence',hprominence);
                        [~,idx] = intersect(hlocs,rlocs2);
                        hlocs(idx) = NaN;
                    case 3 % high-frequency-based "sound" detection
                        if ~exist('sigH','var')
                            [c,d] = butter(2,280*2/app.Rate,'high');
                            heart = filter(c,d,resp-mean(resp,'omitnan'));
                            sigH = movmean(abs(diff(heart)*app.Rate),round(.02*app.Rate));
                        end
                        [~,hlocs] = findpeaks(sigH,'MinPeakDistance',hpeakDistance,'MinPeakProminence',hprominence);
                        if app.AlgorithmCheckBox.Value
                            hpks = sigH(hlocs);
                        else
                            hpks = resp(hlocs);
                        end
                end
                
                % determine heart rate
                if length(hlocs)>1
                    HR = app.Rate*60./diff(hlocs);
                    HR(isnan(HR)) = [];
                    if isempty(HR)
                        HR = 0;
                    else
                        if contains(app.HRDetectDropDown.Value,'Median')
                            HR = round(median(HR,'omitnan'));
                        else
                            HR = round(mean(HR(~isoutlier(HR)),'omitnan'));
                        end
                    end
                    app.HeartRateEditField.Value = HR;
                elseif length(hlocs)==1
                    HR = round(60./TimeLast);
                    HR(isnan(HR)) = [];
                    if length(HR)<1
                        HR=0;
                    end
                    app.HeartRateEditField.Value = HR;
                else
                    HR = 0;
                    app.HeartRateEditField.Value = HR;
                end
                
                rlocs = x(rlocs);
                hpks = hpks(~isnan(hlocs));
                hlocs = x(hlocs(~isnan(hlocs)));
                if app.ShowPeaksCheckBox.Value
                    if isempty(rlocs)
                        set(app.RespPeaks,'XData',NaN,'YData',NaN);
                    else
                        set(app.RespPeaks,'XData',rlocs,'YData',rpks);
                    end
                    if isempty(hlocs)
                        set(app.HeartPeaks,'XData',NaN,'YData',NaN);
                    else
                        set(app.HeartPeaks,'XData',hlocs,'YData',hpks);
                    end
                else
                    set(app.RespPeaks,'XData',NaN,'YData',NaN);
                    set(app.HeartPeaks,'XData',NaN,'YData',NaN);
                end
                
                if app.LogRatestoFileCheckBox.Value
                    fprintf(app.logid,'%s,%.1f,%.0f\n',datetime('now','Format','HH:mm:ss'),RR,HR);
                    %                     dlmwrite(app.logid,[RR HR],'-append');
                end
            end
        end
        
        
    end
    
    methods(Static)
        function result = getSpecialFolder(arg)
            specialFolderType = System.Type.GetType(...
                'System.Environment+SpecialFolder');
            folders = System.Enum.GetValues(specialFolderType);
            enumArg = [];
            for i=1:folders.Length
                if strcmp(char(folders(i)),arg)
                    enumArg = folders(i);
                    break;
                end
            end
            if isempty(enumArg)
                error('Invalid Argument');
            end
            result = char(System.Environment.GetFolderPath(enumArg));
        end
        function devices = IDSerialComs()
            devices = [];
            Skey = 'HKEY_LOCAL_MACHINE\HARDWARE\DEVICEMAP\SERIALCOMM';
            [~, list] = dos(['REG QUERY ' Skey]);
            if ischar(list) && strcmp('ERROR',list(1:5))
                return;
            end
            list = strread(list,'%s','delimiter',' '); %#ok<FPARK> requires strread()
            coms = 0;
            for i = 1:numel(list)
                if strcmp(list{i}(1:3),'COM')
                    if ~iscell(coms)
                        coms = list(i);
                    else
                        coms{end+1} = list{i}; %#ok<AGROW> Loop size is always small
                    end
                end
            end
            key = 'HKEY_LOCAL_MACHINE\SYSTEM\CurrentControlSet\Enum\USB\';
            [~, vals] = dos(['REG QUERY ' key ' /s /f "FriendlyName" /t "REG_SZ"']);
            if ischar(vals) && strcmp('ERROR',vals(1:5))
                return;
            end
            vals = textscan(vals,'%s','delimiter','\t');
            vals = cat(1,vals{:});
            out = 0;
            for i = 1:numel(vals)
                if strcmp(vals{i}(1:min(12,end)),'FriendlyName')
                    if ~iscell(out)
                        out = vals(i);
                    else
                        out{end+1} = vals{i}; %#ok<AGROW> Loop size is always small
                    end
                end
            end
            for i = 1:numel(coms)
                match = strfind(out,[coms{i},')']);
                ind = 0;
                for j = 1:numel(match)
                    if ~isempty(match{j})
                        ind = j;
                    end
                end
                if ind ~= 0
                    com = str2double(coms{i}(4:end));
                    if com > 9
                        length = 8;
                    else
                        length = 7;
                    end
                    devices{i,1} = out{ind}(27:end-length); %#ok<AGROW>
                    devices{i,2} = com; %#ok<AGROW> Loop size is always small
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
            searching = true;
            wbar = uiprogressdlg(app.BreathingSensorUIFigure,'Message',...
                'Looking for Arduino','Title','Breathing Sensor','Indeterminate','on');
            while searching
                Comms = app.IDSerialComs();
                while isa(Comms,'double')
                    val = uiconfirm(app.BreathingSensorUIFigure,'No Arduino detected.','Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Warning');
                    if strcmp(val,'Try again')
                        Comms = app.IDSerialComs();
                    else
                        delete(app);
                        return
                    end
                end
                Comms = reshape(Comms(~cellfun(@isempty,Comms)),[],2);
                while isempty(Comms)
                    val = uiconfirm(app.BreathingSensorUIFigure,'No Arduino detected.','Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Warning');
                    if strcmp(val,'Try again')
                        Comms = app.IDSerialComs();
                    else
                        delete(app);
                        return
                    end
                end
                ArduinoPorts = [Comms{(contains(Comms(:,1),'Arduino')),2}];
                N = numel(ArduinoPorts);
                n=1;
                while n<=N && searching
                    arduino = serial(['COM' int2str(ArduinoPorts(n))]); %#ok<*SERIAL,*TNMLP>
                    arduino.BaudRate = 115200;
                    arduino.Terminator = [];
                    try
                        fopen(arduino);
                        attempts = 0; connection = false;
                        pause(0.1);
                        while attempts < 30 && ~connection
                            fwrite(arduino,0);
                            drawnow;
                            tic;
                            while ~arduino.BytesAvailable && toc<0.1
                                drawnow;
                            end
                            if toc>0.1
                                attempts = attempts+1;
                            else
                                pause(0.1);
                                if arduino.BytesAvailable>5
                                    Program = char(fread(arduino,arduino.BytesAvailable))'; %#ok<*FREAD>
                                    connection = true;
                                    fclose(arduino);
                                end
                            end
                        end
                        if strcmp(Program,'Breathing Sensor With Trigger')
                            searching = false;
                        else
                            n = n+1;
                            fclose(arduino);
                        end
                    catch
                        n = n+1;
                    end
                end
                if searching
                    selection = uiconfirm(app.BreathingSensorUIFigure,'No matching Arduino programs found. Try connecting again?',...
                        'Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Error');
                    if strcmp(selection,'Cancel') || isempty(selection)
                        delete(app);
                        return
                    end
                end
            end
            delete(wbar);
            app.Arduino = arduino;
            app.Arduino.BaudRate = 115200;
            app.Arduino.Terminator = [];
            app.Arduino.InputBufferSize = 1024;
            app.Arduino.BytesAvailableFcnMode = 'byte';
            app.Arduino.BytesAvailableFcnCount = 200;
            app.Arduino.BytesAvailableFcn = @(src,event) recordData(app,src,event);
            fopen(app.Arduino);
            
            app.Rate = app.SampleRateEditField.Value;
            app.Trace = line(app.VitalsDisplay,0,0,'Color','k','LineWidth',1);
            app.RespTrace = line(app.BreathingDisplay,0,0,'Color','b','LineWidth',1);
            app.HeartTrace = line(app.HeartDisplay,0,0,'Color','r','LineWidth',0.5);
            app.HeartPeaks = line(app.VitalsDisplay,NaN,NaN,'Color','r','Marker','v','MarkerSize',6,'LineStyle','none','MarkerFaceColor','r');
            app.RespPeaks = line(app.VitalsDisplay,NaN,NaN,'Color','k','Marker','o','MarkerSize',8,'LineStyle','none','MarkerFaceColor','k');
            app.PathTextArea.Value = [app.getSpecialFolder('Desktop'),filesep];
            app.VitalsDisplay.YLimMode = 'auto';
            app.show_algorithm = false;
        end

        % Value changed function: SampleRateEditField
        function SetSampleRate(app, event)
            value = app.SampleRateEditField.Value;
            app.Rate = value;
            fclose(app.Arduino);
            app.Arduino.BytesAvailableFcnCount = min(max(20,round(value/10)*2),500);
            fopen(app.Arduino);
        end

        % Value changed function: StartButton
        function StartStopRecording(app, event)
            value = app.StartButton.Value;
            if value
                % create binary file to record to
                if app.SavetoFileCheckBox.Value
                    if isempty(app.FileEditField.Value)
                        questdlg('Please enter a name for the file or deselect the record to file option','Breathing Sensor','Ok','Ok');
                        app.StartButton.Value = false;
                        return;
                    else
                        app.FileEditField.Enable = false;
                    end
                    filename = [app.PathTextArea.Value{:} app.FileEditField.Value '.bin'];
                    if exist(filename,'file')
                        q = questdlg('A file with the same name already exists. Overwrite?','Breathing Sensor','Yes','No','No');
                        if not(strcmp(q,'Yes'))
                            app.StartButton.Value = false;
                            app.StartButton.Text = 'Start';
                            app.SavetoFileCheckBox.Enable = true;
                            app.SampleRateEditField.Enable = true;
                            return
                        end
                    end
                    app.FID = fopen(filename,'w');
                    fwrite(app.FID,now,'double');
                    fwrite(app.FID,app.Rate,'double');
                    fwrite(app.FID,1,'uint8');
                    %                     fwrite(app.FID,4,'uint8'); fwrite(app.FID,'Time','char');
                    fwrite(app.FID,6,'uint8'); fwrite(app.FID,'Signal','char');
                end
                % create vitals log file to record to
                if app.LogRatestoFileCheckBox.Value
                    if isempty(app.FileEditField.Value) && ~app.SavetoFileCheckBox.Value
                        questdlg('Please enter a name for the file or deselect the log to file option','Breathing Sensor','Ok','Ok');
                        app.StartButton.Value = false;
                        return;
                    else
                        app.FileEditField.Enable = false;
                    end
                    logFile = [app.PathTextArea.Value{:} app.FileEditField.Value '.csv'];
                    if exist(logFile,'file')
                        q = questdlg('A file with the same name already exists. Overwrite?','Breathing Sensor','Yes','No','No');
                        if not(strcmp(q,'Yes'))
                            app.StartButton.Value = false;
                            app.StartButton.Text = 'Start';
                            app.SavetoFileCheckBox.Enable = true;
                            app.SampleRateEditField.Enable = true;
                            return
                        end
                    end
                    cHeader = {'Time','Breathing Rate','Heart Rate'};
                    textHeader = strjoin(cHeader,',');
                    app.logid = fopen(logFile,'w');
                    fprintf(app.logid,'%s\n',datetime('now','Format','yyyy-MMM-dd'));
                    fprintf(app.logid,'%s\n',textHeader);
                end
                % diable all that could cause an error
                app.SavetoFileCheckBox.Enable = false;
                app.LogRatestoFileCheckBox.Enable = false;
                app.SampleRateEditField.Enable = false;
                app.ToggleNumberEditField.Enable = false;
                app.Trigger.Enable = false;
                app.StartButton.Text = 'Stop';
                % reset time and data vectors
                set(app.HeartPeaks,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'XData',NaN,'YData',NaN);
                app.Data = NaN(ceil(app.Rate*30),1);
                app.Time = zeros(ceil(app.Rate*30),1);
                % send interval spacing between samples in microseconds
                if app.Arduino.BytesAvailable
                    fread(app.Arduino,app.Arduino.BytesAvailable);
                end
                correct_interval = false;
                while ~correct_interval
                    us = max(round(1e6/app.Rate),1);
                    fwrite(app.Arduino,[1 fliplr(double(typecast(uint32(us),'uint8')))]);
                    pause(0.1);
                    if app.Arduino.BytesAvailable
                        output = char(fread(app.Arduino,app.Arduino.BytesAvailable))';
                        interval = str2double(output);
                        if interval == us
                            correct_interval = true;
                        else
                            if app.Arduino.BytesAvailable
                                char(fread(app.Arduino,app.Arduino.BytesAvailable));
                            end
                        end
                    end
                end
                recordOpt = find(contains(app.Trigger.Items,app.Trigger.Value));
                if recordOpt > 3
                    fwrite(app.Arduino,[6 app.ToggleNumberEditField.Value]);
                else
                    fwrite(app.Arduino,1+recordOpt); % command to start recording
                end
            else
                fwrite(app.Arduino,255);
                pause(0.1);
                if app.Arduino.BytesAvailable
                    counts = app.Arduino.BytesAvailable/2;
                    if numel(counts)>1
                        packet = fread(app.Arduino,counts,'uint16');
                        fwrite(app.FID,uint16(packet),'uint16');
                    end
                    if app.SavetoFileCheckBox.Value
                        fclose(app.FID);
                    end
                    if app.LogRatestoFileCheckBox.Value
                        fclose(app.logid);
                    end
                end
                if ~isempty(app.FileEditField.Value)
                    app.SavetoFileCheckBox.Enable = true;
                    app.LogRatestoFileCheckBox.Enable = true;
                end
                app.FileEditField.Enable = true;
                app.SampleRateEditField.Enable = true;
                app.ToggleNumberEditField.Enable = true;
                app.Trigger.Enable = true;
                app.StartButton.Text = 'Start';
            end
        end

        % Value changed function: LowerEditField, UpperEditField
        function SetYRange(app, event)
            value(2) = round(app.UpperEditField.Value,2);
            if value(2)>5.1,value(2) = 5.1;end
            value(1) = round(app.LowerEditField.Value,2);
            if value(1)<-0.1,value(1) = -0.1; end
            if value(1)>=value(2)
                app.LowerEditField.Value = app.VitalsDisplay.YLim(1);
                app.UpperEditField.Value = app.VitalsDisplay.YLim(2);
            else
                app.VitalsDisplay.YLim = value;
                app.VitalsDisplay.YLimMode = 'manual';
                app.AutoRangeCheckBox.Value = false;
            end
        end

        % Value changed function: AutoRangeCheckBox
        function AutoRangeYAxis(app, event)
            value = app.AutoRangeCheckBox.Value;
            if value
                app.VitalsDisplay.YLimMode = 'auto';
            else
                app.VitalsDisplay.YLimMode = 'manual';
                app.VitalsDisplay.YLim = [app.LowerEditField.Value app.UpperEditField.Value];
            end
        end

        % Button pushed function: FileSelectButton
        function ChooseFile(app, event)
            [file,path] = uiputfile([app.PathTextArea.Value{:} '*.bin']);
            if ischar(file)
                if ~app.StartButton.Value
                    app.SavetoFileCheckBox.Enable = true;
                    app.LogRatestoFileCheckBox.Enable = true;
                end
                app.PathTextArea.Value = path;
                if contains(file,'.')
                    idx = find(file=='.',1,'last');
                    app.FileEditField.Value = file(1:idx-1);
                else
                    app.FileEditField.Value = file;
                end
            end
        end

        % Close request function: BreathingSensorUIFigure
        function Close(app, event)
            try
                fclose(app.Arduino);
            catch
            end
            pause(0.5);
            delete(app)
        end

        % Value changed function: FileEditField
        function FileNameAvailable(app, event)
            if ~app.StartButton.Value
                value = ~isempty(app.FileEditField.Value);
                app.LogRatestoFileCheckBox.Enable = value;
                app.SavetoFileCheckBox.Enable = value;
                if ~value
                    app.LogRatestoFileCheckBox.Value = false;
                    app.SavetoFileCheckBox.Value = false;
                end
            end
        end

        % Value changed function: InvertCheckBox
        function FlipSig(app, event)
            %             value = app.InvertCheckBox.Value;
            app.HeartPeaks.YData = 5-app.HeartPeaks.YData;
            app.RespPeaks.YData = 5-app.RespPeaks.YData;
        end

        % Button pushed function: ProminenceButton
        function EstProminence(app, event)
            data = get(app.Trace,'YData');
            data(isnan(data)) = [];
            if ~isempty(data)
                midVal = (median(data)+mean(data))/2;
                datasort = sort(data,'descend');
                N = length(data);
                maxVal = mean(datasort(1:2*ceil(N/100)));
                respprom = (maxVal-midVal)/sqrt(2);
                if ~app.AlgorithmCheckBox.Value
                    opt = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
                    if opt==2
                        respprom = respprom*10;
                    elseif opt==3
                        respprom = (respprom*10)^2;
                    end
                    heartprom = median(abs(data-median(data))); % using median of absolute differences (MAD)
                    app.HeartProminenceEditField.Value = heartprom; % heart prominence will be estimated if the algorithm is not displayed
                end
                app.BreathProminenceEditField.Value = respprom;
            end
        end

        % Value changed function: RRDetectDropDown
        function PeakDetection(app, event)
            lastVal = find(contains(app.RRDetectDropDown.Items,event.PreviousValue));
            opt = find(contains(app.RRDetectDropDown.Items,app.RRDetectDropDown.Value));
            value = app.AlgorithmCheckBox.Value;
            if value && ~strcmp(app.RRDetectDropDown.Value,'Basic Peaks') && ~app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)/2);
                app.BreathingDisplay.Visible = true;
                app.HeartDisplay.Visible = true;
                app.RespPeaks.Parent = app.BreathingDisplay;
                app.HeartPeaks.Parent = app.HeartDisplay;
                app.RespPeaks.MarkerSize = 4;
                app.HeartPeaks.MarkerSize = 3;
                app.show_algorithm = true;
            elseif (~value || strcmp(app.RRDetectDropDown.Value,'Basic Peaks')) && app.show_algorithm && contains(app.HRDetectDropDown.Value,'Basic')
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)*2);
                app.BreathingDisplay.Visible = false;
                app.HeartDisplay.Visible = false;
                app.RespPeaks.Parent = app.VitalsDisplay;
                app.HeartPeaks.Parent = app.VitalsDisplay;
                set(app.HeartTrace,'XData',NaN,'YData',NaN);
                set(app.RespTrace,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'MarkerSize',8,'XData',NaN,'YData',NaN);
                set(app.HeartPeaks,'MarkerSize',6,'XData',NaN,'YData',NaN);
                app.show_algorithm = false;
            end
            switch opt
                case 1 % basic peaks
                    if lastVal == 3
                        app.BreathProminenceEditField.Value = min(max(sqrt(app.BreathProminenceEditField.Value)/10,0.005),5);
                    elseif lastVal == 2
                        app.BreathProminenceEditField.Value = min(max(app.BreathProminenceEditField.Value/10,0.005),5);
                    end
                    app.BreathProminenceEditField.Limits = [0.005 5];
                case 2 % PanTompkins Modified
                    if lastVal == 3
                        app.BreathProminenceEditField.Value = min(max(sqrt(app.BreathProminenceEditField.Value),0.05),50);
                        app.BreathProminenceEditField.Limits = [0.05 50];
                    elseif lastVal == 1
                        app.BreathProminenceEditField.Limits = [0.005 50];
                        app.BreathProminenceEditField.Value = min(max(app.BreathProminenceEditField.Value*10,0.05),50);
                    end
                case 3 % PanTompkins Original
                    app.BreathProminenceEditField.Limits = [0.05 250];
                    if lastVal == 2
                        app.BreathProminenceEditField.Value = min(max(app.BreathProminenceEditField.Value.^2,0.05),250);
                    elseif lastVal == 1
                        app.BreathProminenceEditField.Value = min(max((app.BreathProminenceEditField.Value*10)^2,0.05),250);
                    end
            end
        end

        % Value changed function: AlgorithmCheckBox
        function ShowAlgorithm(app, event)
            value = app.AlgorithmCheckBox.Value;
            if value && (~contains(app.RRDetectDropDown.Value,'Basic') || ~contains(app.HRDetectDropDown.Value,'Basic'))...
                    && ~app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)/2);
                app.BreathingDisplay.Visible = true;
                app.HeartDisplay.Visible = true;
                app.RespPeaks.Parent = app.BreathingDisplay;
                app.HeartPeaks.Parent = app.HeartDisplay;
                app.RespPeaks.MarkerSize = 4;
                app.HeartPeaks.MarkerSize = 3;
                app.show_algorithm = true;
            elseif (~value || strcmp(app.RRDetectDropDown.Value,'Basic')) && app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)*2);
                app.BreathingDisplay.Visible = false;
                app.HeartDisplay.Visible = false;
                app.RespPeaks.Parent = app.VitalsDisplay;
                app.HeartPeaks.Parent = app.VitalsDisplay;
                set(app.HeartTrace,'XData',NaN,'YData',NaN);
                set(app.RespTrace,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'MarkerSize',8,'XData',NaN,'YData',NaN);
                set(app.HeartPeaks,'MarkerSize',6,'XData',NaN,'YData',NaN);
                app.show_algorithm = false;
            end
        end

        % Value changed function: Trigger
        function TriggerToggle(app, event)
            value = app.Trigger.Value;
            if strcmp(value,'Toggle') || strcmp(value,'Per Sample')
                app.ToggleNumberEditField.Visible = true;
            else
                app.ToggleNumberEditField.Visible = false;
            end
        end

        % Value changed function: HRDetectDropDown
        function HeartDetection(app, event)
            value = app.AlgorithmCheckBox.Value;
            if value && ~contains(app.HRDetectDropDown.Value,'Basic') && ~app.show_algorithm
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)/2);
                app.BreathingDisplay.Visible = true;
                app.HeartDisplay.Visible = true;
                app.RespPeaks.Parent = app.BreathingDisplay;
                app.HeartPeaks.Parent = app.HeartDisplay;
                app.RespPeaks.MarkerSize = 4;
                app.HeartPeaks.MarkerSize = 3;
                app.show_algorithm = true;
            elseif (~value || contains(app.HRDetectDropDown.Value,'Basic')) && app.show_algorithm && contains(app.RRDetectDropDown.Value,'Basic')
                app.VitalsDisplay.Position(3) = round(app.VitalsDisplay.Position(3)*2);
                app.BreathingDisplay.Visible = false;
                app.HeartDisplay.Visible = false;
                app.RespPeaks.Parent = app.VitalsDisplay;
                app.HeartPeaks.Parent = app.VitalsDisplay;
                set(app.HeartTrace,'XData',NaN,'YData',NaN);
                set(app.RespTrace,'XData',NaN,'YData',NaN);
                set(app.RespPeaks,'MarkerSize',8,'XData',NaN,'YData',NaN);
                set(app.HeartPeaks,'MarkerSize',6,'XData',NaN,'YData',NaN);
                app.show_algorithm = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BreathingSensorUIFigure = uifigure('Visible', 'off');
            app.BreathingSensorUIFigure.Name = 'Breathing Sensor';
            app.BreathingSensorUIFigure.CloseRequestFcn = createCallbackFcn(app, @Close, true);
            app.VitalsDisplay = uiaxes(app.BreathingSensorUIFigure);
            xlabel(app.VitalsDisplay, 'Time (s)')
            ylabel(app.VitalsDisplay, 'Volts')
            app.VitalsDisplay.XLim = [0 5];
            app.VitalsDisplay.YLim = [0 5];
            app.BreathingDisplay = uiaxes(app.BreathingSensorUIFigure);
            title(app.BreathingDisplay, 'Breathing')
            xlabel(app.BreathingDisplay, 'Time (s)')
            app.BreathingDisplay.XLim = [0 5];
            app.BreathingDisplay.Visible = 'off';
            app.HeartDisplay = uiaxes(app.BreathingSensorUIFigure);
            title(app.HeartDisplay, 'Heart')
            xlabel(app.HeartDisplay, 'Time (s)')
            app.HeartDisplay.XLim = [0 5];
            app.HeartDisplay.Visible = 'off';
            app.RateHzLabel = uilabel(app.BreathingSensorUIFigure);
            app.RateHzLabel.Text = 'Rate (Hz)';
            app.SampleRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.SampleRateEditField.Limits = [1 5000];
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SetSampleRate, true);
            app.SampleRateEditField.Value = 1200;
            app.TimesEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimeEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.TimeEditField.Limits = [1 20];
            app.TimeEditField.Value = 5;
            app.UpperEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.UpperEditField.Limits = [0 5.1];
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @SetYRange, true);
            app.UpperEditField.Value = 5;
            app.LowerEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.LowerEditField.Limits = [-0.1 5];
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @SetYRange, true);
            app.AutoRangeCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.AutoRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRangeYAxis, true);
            app.AutoRangeCheckBox.Tooltip = {'Autoscales y-axis to show piezo signal'};
            app.AutoRangeCheckBox.Text = 'Auto Range';
            app.AutoRangeCheckBox.Value = true;
            app.FileEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.BreathingSensorUIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameAvailable, true);
            app.FileSelectButton = uibutton(app.BreathingSensorUIFigure, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFile, true);
            app.FileSelectButton.Text = '...';
            app.PathTextAreaLabel = uilabel(app.BreathingSensorUIFigure);
            app.PathTextAreaLabel.Text = 'Path';
            app.PathTextArea = uitextarea(app.BreathingSensorUIFigure);
            app.ProminenceEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.ProminenceEditFieldLabel.Text = 'Prominence';
            app.BreathProminenceEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.BreathProminenceEditField.Limits = [0.005 5];
            app.BreathProminenceEditField.Value = 0.2;
            app.MaxRateLabel = uilabel(app.BreathingSensorUIFigure);
            app.MaxRateLabel.Text = 'Max Rate';
            app.MaxBreathingRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.MaxBreathingRateEditField.Limits = [10 300];
            app.MaxBreathingRateEditField.Value = 180;
            app.InvertCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipSig, true);
            app.InvertCheckBox.Text = 'Invert';
            app.BreathingRateEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.BreathingRateEditFieldLabel.Text = 'Breathing Rate';
            app.BreathingRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.BreathingRateEditField.ValueDisplayFormat = '%11.1f';
            app.BreathingRateEditField.Editable = 'off';
            app.StartButton = uibutton(app.BreathingSensorUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartStopRecording, true);
            app.StartButton.Text = 'Start';
            app.SavetoFileCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.SavetoFileCheckBox.Enable = 'off';
            app.SavetoFileCheckBox.Text = 'Save to File';
            app.HeartRateLabel = uilabel(app.BreathingSensorUIFigure);
            app.HeartRateLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.HeartRateEditField.ValueDisplayFormat = '%11.0f';
            app.HeartRateEditField.Editable = 'off';
            app.LogRatestoFileCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.LogRatestoFileCheckBox.Enable = 'off';
            app.LogRatestoFileCheckBox.Text = 'Log Rates';
            app.minLabel = uilabel(app.BreathingSensorUIFigure);
            app.minLabel.Text = '/min';
            app.minLabel_2 = uilabel(app.BreathingSensorUIFigure);
            app.minLabel_2.Text = '/min';
            app.MaxHeartRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.MaxHeartRateEditField.Limits = [10 1000];
            app.MaxHeartRateEditField.Value = 600;
            app.HeartProminenceEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.HeartProminenceEditField.Limits = [0.001 5];
            app.HeartProminenceEditField.Value = 0.02;
            app.ShowPeaksCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.ShowPeaksCheckBox.Text = 'Show Peaks';
            app.ShowPeaksCheckBox.Value = true;
            app.FilterDropDownLabel = uilabel(app.BreathingSensorUIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.BreathingSensorUIFigure);
            app.FilterDropDown.Items = {'None', '20Hz Mov Avg', '40Hz Mov Avg', '50Hz Mov Avg', '60Hz Mov Avg', '50Hz Lowpass', '100Hz Lowpass'};
            app.FilterDropDown.Tooltip = {'Optional filters for data visualization'};
            app.FilterDropDown.Value = '60Hz Mov Avg';
            app.ProminenceButton = uibutton(app.BreathingSensorUIFigure, 'push');
            app.ProminenceButton.ButtonPushedFcn = createCallbackFcn(app, @EstProminence, true);
            app.ProminenceButton.Tooltip = {'Estimates Prominences for Respiratory and Cardiac signals'};
            app.ProminenceButton.Text = 'Prominence';
            app.rpmLabel = uilabel(app.BreathingSensorUIFigure);
            app.rpmLabel.Text = 'rpm';
            app.bpmLabel = uilabel(app.BreathingSensorUIFigure);
            app.bpmLabel.Text = 'bpm';
            app.RRDetectDropDownLabel = uilabel(app.BreathingSensorUIFigure);
            app.RRDetectDropDownLabel.Text = 'RR Detect';
            app.RRDetectDropDown = uidropdown(app.BreathingSensorUIFigure);
            app.RRDetectDropDown.Items = {'Basic Peaks', 'Pan-Tompkins (Modified)', 'Pan-Tompkins (Original)'};
            app.RRDetectDropDown.ValueChangedFcn = createCallbackFcn(app, @PeakDetection, true);
            app.RRDetectDropDown.Value = 'Basic Peaks';
            app.AlgorithmCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.AlgorithmCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAlgorithm, true);
            app.AlgorithmCheckBox.Text = 'Algorithm';
            app.AlgorithmCheckBox.Value = true;
            app.TriggerLabel = uilabel(app.BreathingSensorUIFigure);
            app.TriggerLabel.Text = 'Trigger';
            app.Trigger = uidropdown(app.BreathingSensorUIFigure);
            app.Trigger.Items = {'None', 'External', 'Session', 'Per Sample', 'Toggle'};
            app.Trigger.ValueChangedFcn = createCallbackFcn(app, @TriggerToggle, true);
            app.Trigger.Value = 'None';
            app.ToggleNumberEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.ToggleNumberEditField.Limits = [1 255];
            app.ToggleNumberEditField.RoundFractionalValues = 'on';
            app.ToggleNumberEditField.Visible = 'off';
            app.ToggleNumberEditField.Value = 10;
            app.HRDetectDropDownLabel = uilabel(app.BreathingSensorUIFigure);
            app.HRDetectDropDownLabel.Text = 'HR Detect';
            app.HRDetectDropDown = uidropdown(app.BreathingSensorUIFigure);
            app.HRDetectDropDown.Items = {'Basic Median', 'Basic Average', 'Sound'};
            app.HRDetectDropDown.ValueChangedFcn = createCallbackFcn(app, @HeartDetection, true);
            app.HRDetectDropDown.Value = 'Basic Median';
            % Show the figure after all components are created
            app.BreathingSensorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BreathingSensor

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BreathingSensorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartUp)
            else

                % Focus the running singleton app
                figure(runningApp.BreathingSensorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BreathingSensorUIFigure)
        end
    end
end